classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TexasInstumentsLabel    matlab.ui.control.Label
        TIan85Label             matlab.ui.control.Label
        ResultEditField         matlab.ui.control.NumericEditField
        ResultEditFieldLabel    matlab.ui.control.Label
        CalculateButton         matlab.ui.control.Button
        OperationButtonGroup    matlab.ui.container.ButtonGroup
        MultiplicationButton    matlab.ui.control.RadioButton
        SubtractionButton       matlab.ui.control.RadioButton
        AdditionButton          matlab.ui.control.RadioButton
        Operand2EditField       matlab.ui.control.EditField
        Operand2EditFieldLabel  matlab.ui.control.Label
        Operand1EditField       matlab.ui.control.EditField
        Operand1EditFieldLabel  matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            % Put operands in an array
            rawInput1 = cast(str2double(app.Operand1EditField.Value), "int32");
            rawInput2 = cast(str2double(app.Operand2EditField.Value), "int32");
            input1 = rawInput1;
            input2 = rawInput2;
            for i = 8 : -1 : 1
                input1(i) = mod(rawInput1, 10);
                input2(i) = mod(rawInput2, 10);

                rawInput1 = floor(rawInput1 / 10);
                rawInput2 = floor(rawInput2 / 10);
            end
            
            rawOutput = zeros(1, 9);

            % Enter values into the correct system and return the output as an array 
            mdl = 'Calculator';
            load_system(mdl);
            switch app.OperationButtonGroup.SelectedObject.Text
                case "Addition"
                    
                    % Enter user input into addition calculator input
                    for i = 8 : -1 : 1
                        blockPath = [mdl, '/Add1' num2str(i)];
                        set_param(blockPath, 'Value', int2str(input1(i)));
                    end

                    for i = 8 : -1 : 1
                        blockPath = [mdl, '/Add2' num2str(i)];
                        set_param(blockPath, 'Value', int2str(input2(i)));
                    end

                    % Run simulation and save data
                    simOut = sim('Calculator', 'ReturnWorkspaceOutputs', 'on');
                    AddBus = simOut.logsout.get('AddBus').Values;
                    currentBus = AddBus;
                    for i = 1 : 9
                        signalName = ['AddOut' num2str(i)];
                        rawOutput(i) = currentBus.(signalName).Data(end);
                        if (i < 8) 
                            currentBus = currentBus.('signal1');
                        end
                    end

                case "Subtraction"

                    % Enter user input into addition calculator input
                    for i = 8 : -1 : 1
                        blockPath = [mdl, '/Sub1' num2str(i)];
                        set_param(blockPath, 'Value', int2str(input1(i)));
                    end

                    for i = 8 : -1 : 1
                        blockPath = [mdl, '/Sub2' num2str(i)];
                        set_param(blockPath, 'Value', int2str(input2(i)));
                    end

                    % Run simulation and save data
                    simOut = sim('Calculator', 'ReturnWorkspaceOutputs', 'on');
                    SubBus = simOut.logsout.get('SubBus').Values;
                    currentBus = SubBus;
                    for i = 1 : 9
                        signalName = ['SubOut' num2str(i)];
                        rawOutput(i) = currentBus.(signalName).Data(end);
                        if (i < 8) 
                            currentBus = currentBus.('signal1');
                        end
                    end

                case "Multiplication"
                    
                    % Enter user input into addition calculator input
                    for i = 1 : 4
                        blockPath = [mdl, '/Mult1' num2str(i)];
                        set_param(blockPath, 'Value', int2str(input1(i+4)));
                    end

                    for i = 1 : 4
                        blockPath = [mdl, '/Mult2' num2str(i)];
                        set_param(blockPath, 'Value', int2str(input2(i+4)));
                    end

                    % Run simulation and save data
                    simOut = sim('Calculator', 'ReturnWorkspaceOutputs', 'on');
                    MultBus = simOut.logsout.get('MultBus').Values;
                    currentBus = MultBus;
                    j = 8;
                    for i = 1 : 8
                        signalName = ['MultOut' num2str(i)];
                        rawOutput(j) = currentBus.(signalName).Data(end);
                        if (i < 7) 
                            currentBus = currentBus.('signal2');
                        end
                        j = j - 1;
                    end
            end

            output = 0;
            % Take result out of array
            for i = 9 : -1 : 1
                output = output * 10;
                output = output + rawOutput(i);
            end

            app.ResultEditField.Value = double(output);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Operand1EditFieldLabel = uilabel(app.UIFigure);
            app.Operand1EditFieldLabel.Text = 'Operand 1:';
            app.Operand1EditField = uieditfield(app.UIFigure, 'text');
            app.Operand1EditField.InputType = 'digits';
            app.Operand1EditField.Placeholder = '0';
            app.Operand2EditFieldLabel = uilabel(app.UIFigure);
            app.Operand2EditFieldLabel.Text = 'Operand 2:';
            app.Operand2EditField = uieditfield(app.UIFigure, 'text');
            app.Operand2EditField.InputType = 'digits';
            app.Operand2EditField.Placeholder = '0';
            app.OperationButtonGroup = uibuttongroup(app.UIFigure);
            app.OperationButtonGroup.Title = 'Operation';
            app.AdditionButton = uiradiobutton(app.OperationButtonGroup);
            app.AdditionButton.Text = 'Addition';
            app.AdditionButton.Value = true;
            app.SubtractionButton = uiradiobutton(app.OperationButtonGroup);
            app.SubtractionButton.Text = 'Subtraction';
            app.MultiplicationButton = uiradiobutton(app.OperationButtonGroup);
            app.MultiplicationButton.Text = 'Multiplication';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultEditField.ValueDisplayFormat = '%.0f';
            app.TIan85Label = uilabel(app.UIFigure);
            app.TIan85Label.Text = 'TIan - 85';
            app.TexasInstumentsLabel = uilabel(app.UIFigure);
            app.TexasInstumentsLabel.Text = 'Texas Instuments';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Associate the Simulink Model
            app.Simulation = simulation('Calculator');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end